classdef Wangba < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        TextArea                  matlab.ui.control.TextArea
        TextAreaLabel             matlab.ui.control.Label
        InputPictureButton_2      matlab.ui.control.Button
        TrimapLabel               matlab.ui.control.Label
        Image_4                   matlab.ui.control.Image
        V001Label                 matlab.ui.control.Label
        StartButton               matlab.ui.control.Button
        LGPTBayesMattingLabel     matlab.ui.control.Label
        WindowSizeEditField       matlab.ui.control.NumericEditField
        WindowSizeEditFieldLabel  matlab.ui.control.Label
        InputBackgroundButton     matlab.ui.control.Button
        SavePictureButton         matlab.ui.control.Button
        ResultLabel               matlab.ui.control.Label
        BackgroundLabel           matlab.ui.control.Label
        Image_3                   matlab.ui.control.Image
        Image_2                   matlab.ui.control.Image
        InputPictureButton        matlab.ui.control.Button
        OriginalLabel             matlab.ui.control.Label
        Image                     matlab.ui.control.Image
    end

% 
    methods (Access = private)
        
        function dprint(app, info)
            app.TextArea.Value{end + 1} =  info;
            app.TextArea.scroll("bottom");
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function: Image, StartButton
        function StartButtonPushed(app, event)
            dprint(app, 'start matting');
        end

        % Button pushed function: InputPictureButton
        function InputPictureBtnOnClick(app, event)
            [file, path] = uigetfile({'*.jpg;*.jpeg;*.png'}, 'Select an image file');
            if isequal(file,0)
                return;
            end
            origin_img = imread(fullfile(path, file));
            app.Image.ImageSource = origin_img;
            dprint(app, 'Input picture ready');
        end

        % Button pushed function: InputPictureButton_2
        function InputTrimapBtnOnClick(app, event)
            [file, path] = uigetfile({'*.jpg;*.jpeg;*.png'}, 'Select an image file');
            if isequal(file,0)
                return;
            end
            trimap_img = imread(fullfile(path, file));
            app.Image_2.ImageSource = ind2rgb(trimap_img, gray);
            dprint(app, 'Trimap ready');
        end

        % Button pushed function: InputBackgroundButton
        function InputBackgroundOnClick(app, event)
            [file, path] = uigetfile({'*.jpg;*.jpeg;*.png'}, 'Select an image file');
            if isequal(file,0)
                return;
            end
            bg_img = imread(fullfile(path, file));
            app.Image_3.ImageSource = bg_img;
            dprint(app, 'BackGround ready');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageClickedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.OriginalLabel = uilabel(app.UIFigure);
            app.OriginalLabel.Text = 'Original';
            app.InputPictureButton = uibutton(app.UIFigure, 'push');
            app.InputPictureButton.ButtonPushedFcn = createCallbackFcn(app, @InputPictureBtnOnClick, true);
            app.InputPictureButton.Text = 'Input Picture';
            app.Image_2 = uiimage(app.UIFigure);
            app.Image_3 = uiimage(app.UIFigure);
            app.BackgroundLabel = uilabel(app.UIFigure);
            app.BackgroundLabel.Text = 'Background';
            app.ResultLabel = uilabel(app.UIFigure);
            app.ResultLabel.Text = 'Result';
            app.SavePictureButton = uibutton(app.UIFigure, 'push');
            app.SavePictureButton.Text = 'Save Picture';
            app.InputBackgroundButton = uibutton(app.UIFigure, 'push');
            app.InputBackgroundButton.ButtonPushedFcn = createCallbackFcn(app, @InputBackgroundOnClick, true);
            app.InputBackgroundButton.Text = 'Input Background';
            app.WindowSizeEditFieldLabel = uilabel(app.UIFigure);
            app.WindowSizeEditFieldLabel.Text = 'Window Size';
            app.WindowSizeEditField = uieditfield(app.UIFigure, 'numeric');
            app.LGPTBayesMattingLabel = uilabel(app.UIFigure);
            app.LGPTBayesMattingLabel.Text = 'L-GPT Bayes Matting';
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.V001Label = uilabel(app.UIFigure);
            app.V001Label.Text = 'V 0.0.1';
            app.Image_4 = uiimage(app.UIFigure);
            app.TrimapLabel = uilabel(app.UIFigure);
            app.TrimapLabel.Text = 'Trimap';
            app.InputPictureButton_2 = uibutton(app.UIFigure, 'push');
            app.InputPictureButton_2.ButtonPushedFcn = createCallbackFcn(app, @InputTrimapBtnOnClick, true);
            app.InputPictureButton_2.Text = 'Input Picture';
            app.TextAreaLabel = uilabel(app.UIFigure);
            app.TextAreaLabel.Text = 'Text Area';
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Value = {'System Log'; 'Log Info'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Wangba

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end