classdef Wangba < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        AlphaMapLabel              matlab.ui.control.Label
        SavePictureButton_2        matlab.ui.control.Button
        ResultLabel                matlab.ui.control.Label
        OriginValueEditField       matlab.ui.control.NumericEditField
        OriginValueEditFieldLabel  matlab.ui.control.Label
        SystemLogTextArea          matlab.ui.control.TextArea
        SystemLogTextAreaLabel     matlab.ui.control.Label
        InputPictureButton_2       matlab.ui.control.Button
        TrimapLabel                matlab.ui.control.Label
        V001Label                  matlab.ui.control.Label
        StartButton                matlab.ui.control.Button
        LGPTBayesMattingLabel      matlab.ui.control.Label
        IterationEditField         matlab.ui.control.NumericEditField
        IterationEditFieldLabel    matlab.ui.control.Label
        InputBackgroundButton      matlab.ui.control.Button
        SavePictureButton          matlab.ui.control.Button
        BackgroundLabel            matlab.ui.control.Label
        Image_3                    matlab.ui.control.Image
        Image_2                    matlab.ui.control.Image
        InputPictureButton         matlab.ui.control.Button
        OriginalLabel              matlab.ui.control.Label
        Image                      matlab.ui.control.Image
        UIAxes_2                   matlab.ui.control.UIAxes
        UIAxes                     matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        Property % Description
        ImageSource
        bg_img
        origin_img
        trimap_img
        FThreshold =  255 * 0.95
        G_OriFilePath
        G_TriFilePath
        G_BackFilePath = pwd + "\WhiteBg.png"
        unknownF
        unknownAlpha
    end
% 
    methods (Access = private)
        
        function dprint(app, info)
            app.SystemLogTextArea.Value{end + 1} =  info;
            app.SystemLogTextArea.scroll("bottom");
        end

     
        function results = func2(app)
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function: Image, StartButton
        function StartButtonPushed(app, event)
            dprint(app, 'Start matting...');
            % preprocessing images rgb->gray
            dprint(app, 'Start Preprocessing...');

            [frontImg, backImg, unknownImg] = FunPreProcess(app.G_OriFilePath, app.G_TriFilePath);
            dprint(app, 'Preprocess successful');
            % calculation
            dprint(app, 'Start Calculating...');

            [Fmean, Bmean, coF,coB] = FunStatisticalAnalysis(frontImg, backImg);
           
            oriVar = app.OriginValueEditField.Value;
            Iteration = app.IterationEditField.Value;
            triMap = app.G_TriFilePath;
            dprint(app, 'Start Calculate unknownAlpha...');
            [app.unknownAlpha, app.unknownF, unknownB] = Matting(unknownImg, ...
                triMap, coF, coB, Fmean, Bmean, oriVar, Iteration);
            dprint(app, 'Start to print unknownAlpha...');
            image_unk = uint8(app.unknownAlpha*255);
            imshow(image_unk, 'Parent', app.UIAxes_2);
            dprint(app, 'Result shown as Figure 4');
            
            % show the Matting result in AXIS
            dprint(app, 'Start Matting...');
            oriImg = app.G_OriFilePath;
            triMap = app.G_TriFilePath;


            newBack = GiveNewBackground(oriImg, triMap, app.unknownF, ...
                app.unknownAlpha, app.FThreshold, app.G_BackFilePath);
            imageData = (newBack);
            dprint(app, 'Start to print the result...');
            imshow(imageData, 'Parent', app.UIAxes);
            dprint(app, 'Result shown as Figure 5');
            dprint(app, 'Calculation End');
        end

        % Button pushed function: InputPictureButton
        function InputPictureBtnOnClick(app, event)
            [file, path] = uigetfile({'*.jpg;*.jpeg;*.png'}, 'Select an image file');
            if isequal(file,0)
                return;
            end
            app.G_OriFilePath = fullfile(path, file);
            app.origin_img = imread(fullfile(path, file));
            app.Image.ImageSource = app.origin_img;
            dprint(app, 'Input picture ready');
        end

        % Button pushed function: InputPictureButton_2
        function InputTrimapBtnOnClick(app, event)
            [file, path] = uigetfile({'*.jpg;*.jpeg;*.png'}, 'Select an image file');
            if isequal(file,0)
                return;
            end
            app.G_TriFilePath = fullfile(path, file);
            app.trimap_img = imread(fullfile(path, file));
            % if pic is not rgb mode, change it to rgb mode
            if (ndims(app.trimap_img) == 2)
                app.trimap_img = cat(3, app.trimap_img, app.trimap_img, app.trimap_img);
            end
            app.Image_2.ImageSource = app.trimap_img;
            dprint(app, 'Trimap ready');
        end

        % Button pushed function: InputBackgroundButton
        function InputBackgroundOnClick(app, event)
            [file, path] = uigetfile({'*.jpg;*.jpeg;*.png'}, 'Select an image file');
            if isequal(file,0)
                return;
            end
            app.G_BackFilePath = fullfile(path, file);
            app.bg_img = imread(fullfile(path, file));
            app.Image_3.ImageSource = app.bg_img;
            dprint(app, 'BackGround ready');
        end

        % Button pushed function: SavePictureButton
        function SavePictureButtonPushed(app, event)
            % choosing pics type that u want to saved
            filter = {'*.jpg';'*.png';'*.tif';'*.pdf';'*.eps'};
            [filename ,filepath]=uiputfile(filter,'Save Result');%pathname获取保存数据路径，filename获取保存数据名称
            if isequal(filename,0) || isequal(filepath,0)
                errordlg("You didn't choose saving path and filename, please retry","Warning")
                dprint(app, 'Save image not successful');
                return
            end
            dprint(app, 'Export Success');
            exportgraphics(app.UIAxes,fullfile(filepath,filename),'Resolution',600)
            %dprint(app, 'Warning');
        end

        % Value changed function: SystemLogTextArea
        function SystemLogTextAreaValueChanged(app, event)
            value = app.SystemLogTextArea.Value;
            
        end

        % Callback function: SystemLogTextArea
        function SystemLogTextAreaValueChanging(app, event)
            changingValue = event.Value;
            
        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
            axis(app.UIAxes,'off');

        end

        % Callback function
        function ChangeBackgroundButtonPushed(app, event)
        
        end

        % Button pushed function: SavePictureButton_2
        function SavePictureButton_2Pushed(app, event)
            % choosing pics type that u want to saved
            filter = {'*.jpg';'*.png';'*.tif';'*.pdf';'*.eps'};
            [filename ,filepath]=uiputfile(filter,'Save Result');%pathname获取保存数据路径，filename获取保存数据名称
            if isequal(filename,0) || isequal(filepath,0)
                errordlg("You didn't choose saving path and filename, please retry","Warning")
                dprint(app, 'Save image not successful');
                return
            end
            dprint(app, 'Export Success');
            exportgraphics(app.UIAxes_2,fullfile(filepath,filename),'Resolution',600)
            %dprint(app, 'Warning');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.UIAxes_2 = uiaxes(app.UIFigure);
            app.UIAxes_2.XTick = [];
            app.UIAxes_2.YTick = [];
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageClickedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.OriginalLabel = uilabel(app.UIFigure);
            app.OriginalLabel.Text = 'Original';
            app.InputPictureButton = uibutton(app.UIFigure, 'push');
            app.InputPictureButton.ButtonPushedFcn = createCallbackFcn(app, @InputPictureBtnOnClick, true);
            app.InputPictureButton.Text = 'Input Picture';
            app.Image_2 = uiimage(app.UIFigure);
            app.Image_3 = uiimage(app.UIFigure);
            app.BackgroundLabel = uilabel(app.UIFigure);
            app.BackgroundLabel.Text = 'Background';
            app.SavePictureButton = uibutton(app.UIFigure, 'push');
            app.SavePictureButton.ButtonPushedFcn = createCallbackFcn(app, @SavePictureButtonPushed, true);
            app.SavePictureButton.Text = 'Save Picture';
            app.InputBackgroundButton = uibutton(app.UIFigure, 'push');
            app.InputBackgroundButton.ButtonPushedFcn = createCallbackFcn(app, @InputBackgroundOnClick, true);
            app.InputBackgroundButton.Text = 'Input Background';
            app.IterationEditFieldLabel = uilabel(app.UIFigure);
            app.IterationEditFieldLabel.Text = 'Iteration';
            app.IterationEditField = uieditfield(app.UIFigure, 'numeric');
            app.IterationEditField.Value = 20;
            app.LGPTBayesMattingLabel = uilabel(app.UIFigure);
            app.LGPTBayesMattingLabel.Text = 'L-GPT Bayes Matting';
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.V001Label = uilabel(app.UIFigure);
            app.V001Label.Text = 'V 0.0.1';
            app.TrimapLabel = uilabel(app.UIFigure);
            app.TrimapLabel.Text = 'Trimap';
            app.InputPictureButton_2 = uibutton(app.UIFigure, 'push');
            app.InputPictureButton_2.ButtonPushedFcn = createCallbackFcn(app, @InputTrimapBtnOnClick, true);
            app.InputPictureButton_2.Text = 'Input Picture';
            app.SystemLogTextAreaLabel = uilabel(app.UIFigure);
            app.SystemLogTextAreaLabel.Text = 'System Log';
            app.SystemLogTextArea = uitextarea(app.UIFigure);
            app.SystemLogTextArea.ValueChangedFcn = createCallbackFcn(app, @SystemLogTextAreaValueChanged, true);
            app.SystemLogTextArea.ValueChangingFcn = createCallbackFcn(app, @SystemLogTextAreaValueChanging, true);
            app.SystemLogTextArea.Value = {'System Log'; 'Log Info'};
            app.OriginValueEditFieldLabel = uilabel(app.UIFigure);
            app.OriginValueEditFieldLabel.Text = 'OriginValue';
            app.OriginValueEditField = uieditfield(app.UIFigure, 'numeric');
            app.OriginValueEditField.Value = 10;
            app.ResultLabel = uilabel(app.UIFigure);
            app.ResultLabel.Text = 'Result';
            app.SavePictureButton_2 = uibutton(app.UIFigure, 'push');
            app.SavePictureButton_2.ButtonPushedFcn = createCallbackFcn(app, @SavePictureButton_2Pushed, true);
            app.SavePictureButton_2.Text = 'Save Picture';
            app.AlphaMapLabel = uilabel(app.UIFigure);
            app.AlphaMapLabel.Text = 'AlphaMap';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Wangba

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end