classdef Wangba < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        SystemInitialisationButton  matlab.ui.control.Button
        IterationEditField          matlab.ui.control.NumericEditField
        IterationEditFieldLabel     matlab.ui.control.Label
        AlphaMapLabel               matlab.ui.control.Label
        SavePictureButton_2         matlab.ui.control.Button
        ResultLabel                 matlab.ui.control.Label
        OriginValueEditField        matlab.ui.control.NumericEditField
        OriginValueEditFieldLabel   matlab.ui.control.Label
        SystemLogTextArea           matlab.ui.control.TextArea
        SystemLogTextAreaLabel      matlab.ui.control.Label
        InputPictureButton_2        matlab.ui.control.Button
        TrimapLabel                 matlab.ui.control.Label
        V001Label                   matlab.ui.control.Label
        StartButton                 matlab.ui.control.Button
        LGPTBayesMattingLabel       matlab.ui.control.Label
        InputBackgroundButton       matlab.ui.control.Button
        SavePictureButton           matlab.ui.control.Button
        BackgroundLabel             matlab.ui.control.Label
        Image_3                     matlab.ui.control.Image
        Image_2                     matlab.ui.control.Image
        InputPictureButton          matlab.ui.control.Button
        OriginalLabel               matlab.ui.control.Label
        Image                       matlab.ui.control.Image
        UIAxes_2                    matlab.ui.control.UIAxes
        UIAxes                      matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        Property % Description
        ImageSource
        bg_img
        origin_img
        trimap_img
        FThreshold =  255 * 0.95
        G_OriFilePath
        G_TriFilePath
        G_BackFilePath = pwd + "\WhiteBg.png"
        unknownF
        unknownAlpha
        image_unk
        LastLogTime datetime  % Time of the last log entry
        size1
        size2
    end
% 
    methods (Access = private)
        % function dprint(app, info)
        %     app.SystemLogTextArea.Value{end + 1} =  info;
        %     app.SystemLogTextArea.scroll("bottom");
        % end

    end
    
    methods (Access = public)
        
       function updateLog(app, newMessage)
        % Append new message to the log
        currentTime = datetime('now', 'Format', 'yyyy-MM-dd HH:mm:ss');
        timeDifference = currentTime - app.LastLogTime;
        app.LastLogTime = currentTime; % Update LastLogTime to current
        % Format the time difference (e.g., in seconds)
        timeDiffSeconds = seconds(timeDifference);
        timeDiffMessage = sprintf('[+%0.2f seconds] ', timeDiffSeconds);
        % Combine timestamp with message
        timeMessage = [char(currentTime) ' ' timeDiffMessage '- ' newMessage];
        % Append new message to the log
        currentLog = app.SystemLogTextArea.Value;  % Get current log
        updatedLog = [currentLog; {timeMessage}];  % Append new message
        app.SystemLogTextArea.Value = updatedLog;  % Update the TextArea
        % Auto-scroll to the bottom
        app.SystemLogTextArea.scroll("bottom");
        % Force the user interface to update
        drawnow;
       end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            updateLog(app, 'This is a log message.');

            if isequal(app.Image.ImageSource,'')
                uialert(app.UIFigure, 'No Original input detected.', 'Error', 'Icon', 'Error');
                updateLog(app, 'Input Error with no Original input');
                return;
            elseif isequal(app.Image_2.ImageSource,'')
                uialert(app.UIFigure, 'No Trimap input detected.', 'Error', 'Icon', 'Error');
                updateLog(app, 'Input Error with no Trimap input');
                return;
            elseif ~isequal(app.size1, app.size2)
                uialert(app.UIFigure, 'Input image size does not match.', 'Error', 'Icon', 'Error');
                updateLog(app, 'Input Error with wrong size');
                return;
            end
            
            if isequal(app.Image_3.ImageSource,'')
                uialert(app.UIFigure, 'No background detected, default background will be used.', 'Warning', 'Icon', 'Warning');
                %app.Image_3.ImageSource = imread('E:\CM_Matting\005Submisson\002Week6Submission\001MainProject(finished)\project\WhiteBg.png');
                app.Image_3.ImageSource = imread('WhiteBg.png');
                %Changhong 2024 02 28 Dont use absolute path
                updateLog(app, 'Using default background');
            end

            % preprocessing images rgb->gray
            updateLog(app, 'Start Preprocessing...');

            % Disable button at the start of the main programme
            app.InputBackgroundButton.Enable = 'off';
            app.InputPictureButton.Enable = 'off';
            app.InputPictureButton_2.Enable = 'off';
            app.SavePictureButton.Enable = 'off';
            app.SavePictureButton_2.Enable = 'off';
            app.SystemInitialisationButton.Enable = 'off';

            [frontImg, backImg, unknownImg] = FunPreProcess(app.G_OriFilePath, app.G_TriFilePath);
            updateLog(app, 'Preprocess successful');
            % calculation
            updateLog(app, 'Start Calculating...');

            [Fmean, Bmean, coF,coB] = FunStatisticalAnalysis(frontImg, backImg);
           
            oriVar = app.OriginValueEditField.Value;
            Iteration = app.IterationEditField.Value;
            triMap = app.G_TriFilePath;
            updateLog(app, 'Start Calculate unknownAlpha...');
            [app.unknownAlpha, app.unknownF, unknownB] = Matting(unknownImg, ...
                triMap, coF, coB, Fmean, Bmean, oriVar, Iteration);
            updateLog(app, 'Start to print unknownAlpha...');
            app.image_unk = uint8(app.unknownAlpha*255);
            imshow(app.image_unk, 'Parent', app.UIAxes_2);
            updateLog(app, 'Result shown as Figure 4');
            
            % show the Matting result in AXIS
            updateLog(app, 'Start Matting...');
            oriImg = app.G_OriFilePath;
            triMap = app.G_TriFilePath;


            newBack = GiveNewBackground(oriImg, triMap, app.unknownF, ...
                app.unknownAlpha, app.FThreshold, app.G_BackFilePath);
            imageData = (newBack);
            updateLog(app, 'Start to print the result...');
            imshow(imageData, 'Parent', app.UIAxes);
            updateLog(app, 'Result shown as Figure 5');
            updateLog(app, 'Calculation End');
            uialert(app.UIFigure, 'Task completed successfully.', 'Task Complete', 'Icon', 'success');
            
            % Enable button at the end of the main programme
            app.InputBackgroundButton.Enable = 'on';
            app.InputPictureButton.Enable = 'on';
            app.InputPictureButton_2.Enable = 'on';
            app.SavePictureButton.Enable = 'on';
            app.SavePictureButton_2.Enable = 'on';
            app.SystemInitialisationButton.Enable = 'on';
        end

        % Button pushed function: InputPictureButton
        function InputPictureBtnOnClick(app, event)
            [file, path] = uigetfile({'*.jpg;*.jpeg;*.png'}, 'Select an image file');
            if isequal(file,0)
                return;
            end
            app.G_OriFilePath = fullfile(path, file);
            app.origin_img = imread(fullfile(path, file));
            app.size1 = size(app.origin_img);
            app.Image.ImageSource = app.origin_img;
            updateLog(app, 'Input picture ready');
        end

        % Button pushed function: InputPictureButton_2
        function InputTrimapBtnOnClick(app, event)
            [file, path] = uigetfile({'*.jpg;*.jpeg;*.png'}, 'Select an image file');
            if isequal(file,0)
                return;
            end
            app.G_TriFilePath = fullfile(path, file);
            app.trimap_img = imread(fullfile(path, file));

            % if pic is not rgb mode, change it to rgb mode
            if ndims(app.trimap_img) == 3 && size(app.trimap_img, 3) == 3
                updateLog(app, 'Trimap Size is suitable');
            else
                % Image is not three-dimensional, inform the user
                uialert(app.UIFigure, ...
                    ['The selected image is not a three-dimensional image. ' ...
                    'It will be converted to RGB format.'], ...
                    'Image Format Warning', 'Icon', 'warning');
                app.trimap_img = cat(3, app.trimap_img, app.trimap_img, app.trimap_img);
            end
            
            app.size2 = size(app.trimap_img);

            if (file == "Noise_tri_Fig1.jpg")
                uialert(app.UIFigure, 'The input have noise.', 'Warning', 'Icon', 'Warning');
                updateLog(app, 'Noise Trimap Adding...');
            end

            app.Image_2.ImageSource = app.trimap_img;
            updateLog(app, 'Trimap ready');
        end

        % Button pushed function: InputBackgroundButton
        function InputBackgroundOnClick(app, event)
            [file, path] = uigetfile({'*.jpg;*.jpeg;*.png'}, 'Select an image file');
            if isequal(file,0)
                return;
            end
            app.G_BackFilePath = fullfile(path, file);
            app.bg_img = imread(fullfile(path, file));
            app.Image_3.ImageSource = app.bg_img;
            updateLog(app, 'BackGround ready');
        end

        % Button pushed function: SavePictureButton
        function SavePictureButtonPushed(app, event)
            % Juge wether is saving a empty graph or not
            if isempty(app.UIAxes_2.Children)
                % Displays an error message if there are no graphic objects
                uialert(app.UIFigure, 'No image display in UIAxes.', 'Error', 'Icon', 'Error');
                updateLog(app, 'Save Error');
                return;
            end
            
            % choosing pics type that u want to saved
            filter = {'*.jpg';'*.png';'*.tif';'*.pdf';'*.eps'};
            [filename ,filepath]=uiputfile(filter,'Save Result');
            if isequal(filename,0) || isequal(filepath,0)
                uialert(app.UIFigure, 'You didn not choose saving path and filename, please retry.', 'Warning', 'Icon', 'Warning');
                updateLog(app, 'Save image not successful');
                return;
            end
            updateLog(app, 'Export Success');

            exportgraphics(app.UIAxes, fullfile(filepath,filename));
        end

        % Value changed function: SystemLogTextArea
        function SystemLogTextAreaValueChanged(app, event)
            app.SystemLogTextArea.Editable = "off";
        end

        % Callback function: SystemLogTextArea
        function SystemLogTextAreaValueChanging(app, event)
            app.SystemLogTextArea.Editable = "off";
        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
            axis(app.UIAxes,'off');
        end

        % Callback function
        function ChangeBackgroundButtonPushed(app, event)
        
        end

        % Button pushed function: SavePictureButton_2
        function SavePictureButton_2Pushed(app, event)
            % choosing pics type that u want to saved
            if isempty(app.UIAxes.Children)
                % Displays an error message if there are no graphic objects
                uialert(app.UIFigure, 'No image display in UIAxes.', 'Error', 'Icon', 'Error');
                updateLog(app, 'Save Error');
                return;
            end
            filter = {'*.jpg';'*.png';'*.tif';'*.pdf';'*.eps'};
            [filename ,filepath]=uiputfile(filter,'Save Result');
            if isequal(filename,0) || isequal(filepath,0)
                uialert(app.UIFigure, 'You didn not choose saving path and filename, please retry.', 'Warning', 'Icon', 'Warning');
                updateLog(app, 'Save image not successful');
                return;
            end
            updateLog(app, 'Export Success');
            imwrite(app.image_unk, fullfile(filepath,filename));
        end

        % Button pushed function: SystemInitialisationButton
        function SystemInitialisationButtonPushed(app, event)
            % initialize the system log
            app.SystemLogTextArea.Value = {'System Log Initialized...', ''};
            % initialize the time counter
            app.LastLogTime = datetime('now');
            % set all the value = 0 ;
            % Changhong 2024 02 07 set the Iteration and Origin Value to
            % zero will lead to errors
            app.IterationEditField.Value = 10;
            app.OriginValueEditField.Value = 10;
            % set all the pics into 0
            % app.Image.ImageSource = zeros(size(app.Image.ImageSource));
            % app.Image_2.ImageSource = zeros(size(app.Image_2.ImageSource));
            % app.Image_3.ImageSource = zeros(size(app.Image_3.ImageSource));
            app.Image.ImageSource = '';
            app.Image_2.ImageSource = '';
            app.Image_3.ImageSource = '';
            cla(app.UIAxes);
            cla(app.UIAxes_2);
        end

        % Value changed function: IterationEditField
        function IterationEditFieldValueChanged(app, event)
            value = app.IterationEditField.Value;
            if (value <= 0)
                uialert(app.UIFigure, 'Input value must be positive.', 'Error', 'Icon', 'Error');
                updateLog(app, 'Input Error');
                % Changhong 2024 02 27 must assign a value when value not
                % valid
                app.IterationEditField.Value = 10;
                
            end
        end

        % Value changed function: OriginValueEditField
        function OriginValueEditFieldValueChanged(app, event)
            value = app.OriginValueEditField.Value;
            if value <= 0
                uialert(app.UIFigure, 'Input value must be positive.', 'Error', 'Icon', 'Error');
                updateLog(app, 'Input Error');
                % Changhong 2024 02 27 must assign a value when value not
                % valid
                app.OriginValueEditField.Value = 10;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.UIAxes_2 = uiaxes(app.UIFigure);
            app.UIAxes_2.XTick = [];
            app.UIAxes_2.YTick = [];
            app.Image = uiimage(app.UIFigure);
            app.OriginalLabel = uilabel(app.UIFigure);
            app.OriginalLabel.Text = 'Original';
            app.InputPictureButton = uibutton(app.UIFigure, 'push');
            app.InputPictureButton.ButtonPushedFcn = createCallbackFcn(app, @InputPictureBtnOnClick, true);
            app.InputPictureButton.Text = 'Input Picture';
            app.Image_2 = uiimage(app.UIFigure);
            app.Image_3 = uiimage(app.UIFigure);
            app.BackgroundLabel = uilabel(app.UIFigure);
            app.BackgroundLabel.Text = 'Background';
            app.SavePictureButton = uibutton(app.UIFigure, 'push');
            app.SavePictureButton.ButtonPushedFcn = createCallbackFcn(app, @SavePictureButtonPushed, true);
            app.SavePictureButton.Text = 'Save Picture';
            app.InputBackgroundButton = uibutton(app.UIFigure, 'push');
            app.InputBackgroundButton.ButtonPushedFcn = createCallbackFcn(app, @InputBackgroundOnClick, true);
            app.InputBackgroundButton.Text = 'Input Background';
            app.LGPTBayesMattingLabel = uilabel(app.UIFigure);
            app.LGPTBayesMattingLabel.Text = 'L-GPT Bayes Matting';
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.V001Label = uilabel(app.UIFigure);
            app.V001Label.Text = 'V 0.0.1';
            app.TrimapLabel = uilabel(app.UIFigure);
            app.TrimapLabel.Text = 'Trimap';
            app.InputPictureButton_2 = uibutton(app.UIFigure, 'push');
            app.InputPictureButton_2.ButtonPushedFcn = createCallbackFcn(app, @InputTrimapBtnOnClick, true);
            app.InputPictureButton_2.Text = 'Input Picture';
            app.SystemLogTextAreaLabel = uilabel(app.UIFigure);
            app.SystemLogTextAreaLabel.Text = 'System Log';
            app.SystemLogTextArea = uitextarea(app.UIFigure);
            app.SystemLogTextArea.ValueChangedFcn = createCallbackFcn(app, @SystemLogTextAreaValueChanged, true);
            app.SystemLogTextArea.ValueChangingFcn = createCallbackFcn(app, @SystemLogTextAreaValueChanging, true);
            app.SystemLogTextArea.Editable = 'off';
            app.SystemLogTextArea.Value = {'System Log'; 'Log Info'};
            app.OriginValueEditFieldLabel = uilabel(app.UIFigure);
            app.OriginValueEditFieldLabel.Text = 'OriginValue';
            app.OriginValueEditField = uieditfield(app.UIFigure, 'numeric');
            app.OriginValueEditField.ValueChangedFcn = createCallbackFcn(app, @OriginValueEditFieldValueChanged, true);
            app.OriginValueEditField.Value = 10;
            app.ResultLabel = uilabel(app.UIFigure);
            app.ResultLabel.Text = 'Result';
            app.SavePictureButton_2 = uibutton(app.UIFigure, 'push');
            app.SavePictureButton_2.ButtonPushedFcn = createCallbackFcn(app, @SavePictureButton_2Pushed, true);
            app.SavePictureButton_2.Text = 'Save Picture';
            app.AlphaMapLabel = uilabel(app.UIFigure);
            app.AlphaMapLabel.Text = 'AlphaMap';
            app.IterationEditFieldLabel = uilabel(app.UIFigure);
            app.IterationEditFieldLabel.Text = 'Iteration';
            app.IterationEditField = uieditfield(app.UIFigure, 'numeric');
            app.IterationEditField.ValueChangedFcn = createCallbackFcn(app, @IterationEditFieldValueChanged, true);
            app.IterationEditField.Value = 10;
            app.SystemInitialisationButton = uibutton(app.UIFigure, 'push');
            app.SystemInitialisationButton.ButtonPushedFcn = createCallbackFcn(app, @SystemInitialisationButtonPushed, true);
            app.SystemInitialisationButton.Text = 'System Initialisation';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Wangba

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end